classdef NNB_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ProcessImagesTab                matlab.ui.container.Tab
        FijiPathEditFieldLabel          matlab.ui.control.Label
        FijiPathEditField               matlab.ui.control.EditField
        ChannelDropDown                 matlab.ui.control.DropDown
        ChannelDropDownLabel            matlab.ui.control.Label
        InputFileTypeDropDown           matlab.ui.control.DropDown
        InputFileTypeDropDownLabel      matlab.ui.control.Label
        MaturationEfficiencyEditField   matlab.ui.control.NumericEditField
        MaturationEfficiencyEditFieldLabel  matlab.ui.control.Label
        InputPathEditField              matlab.ui.control.EditField
        InputPathEditFieldLabel         matlab.ui.control.Label
        QualitythresholdEditField       matlab.ui.control.NumericEditField
        QualitythresholdEditFieldLabel  matlab.ui.control.Label
        SpotRadiusEditField             matlab.ui.control.NumericEditField
        SpotRadiusEditFieldLabel        matlab.ui.control.Label
        LoadLastSettingsButton          matlab.ui.control.Button
        DisplayStatus                   matlab.ui.control.EditField
        ProcessNNBTimelapsesLabel       matlab.ui.control.Label
        SelecttheinputfolderLabel       matlab.ui.control.Label
        SelectFolderButton              matlab.ui.control.Button
        SelecttheFijiappfolderLabel     matlab.ui.control.Label
        SelectFijiFolderButton          matlab.ui.control.Button
        ProcessImagesButton             matlab.ui.control.Button
        ColocalizationCalculationsTab   matlab.ui.container.Tab
    end

    
    properties (Access = private)
        RootFolder %the home directory
        ValidInputs %table containing inputFieldNames and verification
        fijiPath
        fijiExecutablePath
    end

    methods (Access = private)

        function [] = processImages(app, inputFolder, spot_radius, quality_threshold, maturationEfficiency, ext, channel)
            % This function processes a folder of raw image stacks and
            % creates MATLAB figures for wach image stack conatining
            % flouresence over time traces that can be classified and
            % converted into approximate oligomeric states

            progressBar = waitbar(0,'Starting Up');

            %add the scripts folder to the path so matlab knows where
            %ImageJ script is located
            scriptsPath = fullfile(app.fijiPath, 'scripts');
            if ~isempty(scriptsPath)
                addpath(scriptsPath)
            else
                error('No ImageJ scripts folder found in Fiji file path')
            end
            
            % add the application's folder ot the path so MATLAB knows
            % where to look for subfunctions like tallySum when running the
            % figures
            addpath(app.RootFolder)
            savepath
            
            % Check if a Results folder already exists and warn the user
            results_folder = fullfile(inputFolder, 'Results');
            if isfolder(results_folder)
                answer = '';
                while ~strcmp(answer,'Continue')
                    answer = questdlg('Warning! a Results Folder already exists. Proceeding could overwrite previous data'...
                        , 'Existing Data', 'Continue', 'Cancel', 'Continue');
                    if strcmp(answer, 'Cancel') || strcmp(answer, '')
                        error('Execution canceled by folder check dialog box')
                    end
                end
            else
                mkdir(results_folder)
            end
            inputs = dictionary(...
                "Input Folder", inputFolder, ...
                "Spot Radius", spot_radius, ...
                "Quality Threshold", quality_threshold, ...
                "Maturation Efficiency", maturationEfficiency, ...
                "File extension", ext, ...
                "Image Channel", channel);

            % Get the list of files with the correct extension in the provided folder
            files = dir(fullfile(inputFolder,ext));
            filenames = {files.name};
            filenames = sort(filenames);
            totalAcquisitions = numel(filenames);

            % Start ImageJ 
            ImageJ;
            import ij.*

            % create the array of figures so that we can loop through them at the end
            figures = gobjects(1,totalAcquisitions);

            
            % Process each image
            for index = 1:length(filenames)

                % make a subfolder for the current image stack
                currentfilename = filenames{index};
                imageName = extractBefore(currentfilename, '.nd2');
                subFolderPath = fullfile(results_folder, imageName);                
                if ~isfolder(subFolderPath)
                    mkdir(subFolderPath);
                end
                processingInfo.AppInputSettings = inputs;
                processingInfo.status = "initializing";
                infoPath = fullfile(subFolderPath, 'processingInfo.mat');
                save(infoPath, "processingInfo")

                waitbar((index-1)/totalAcquisitions, progressBar, ['Processing ', imageName])
                updateStatus(app, ['Processing ', imageName]);

                % convert the image to the tiff file type for MATLAB
                % reading
                convertedImagePath = convertToTiff(inputFolder, subFolderPath, currentfilename, ext);
                
                processingInfo.imageName = imageName;
                processingInfo.status = "converted image";
                save(infoPath, "processingInfo")


                % Convert the image stack into a matlab array and subtract
                % the background to adjust for uneven illumination
                [~, ~, subtractedStack, background] = readInTimelapse(convertedImagePath);
                processingInfo.background = background;
                processingInfo.status = "background corrected";
                save(infoPath, "processingInfo")

                % Sum together the first three frames to reduce noise and
                % create a stack of five duplicates to simulate a video for
                % Trackmate spot detection
                projectionSize = 3;
                [firstFramesProjectionPath, firstFramesProjection] = generateFirstFramesProjection(subFolderPath, subtractedStack(:,:,1:projectionSize));
                processingInfo.projection = firstFramesProjection;
                processingInfo.status = "projection created";
                save(infoPath, "processingInfo")

                % Use Trackmate to identify spot locations and save the
                % data to an xml
                saveTrackXML(app.fijiExecutablePath, app.RootFolder, firstFramesProjectionPath, subFolderPath, spot_radius, quality_threshold, channel)
                trackXMLPath = fullfile(subFolderPath, 'TrackmateData.xml');
                if isfile(trackXMLPath)
                    processingInfo.TrackmateXML = readstruct(trackXMLPath);
                    processingInfo.status = "Trackmate successful";
                    save(infoPath, "processingInfo")
                else
                    error("Trackmate failed")
                end

                % Extract brightness data for 5x5 roi's for each valid
                % identified spot in all collected frames and save in a csv
                [spot_info] = prepareFolderForFigureCreation(processingInfo.TrackmateXML, subtractedStack);
                processingInfo.TraceData = spot_info;
                processingInfo.status = "Traces Extracted";
                save(infoPath, "processingInfo")

                % identify bad spots and add tags to SpotInfoData.csv
                [spot_info] = identifyOverlapSpots(spot_info, firstFramesProjection, 5, 9, 2043, 4.5);
                processingInfo.TraceData = spot_info;
                processingInfo.status = "Overlaps checked";
                save(infoPath, "processingInfo")

                % instantiate the figure and make them invisible so that 
                % MATLAB doesn't steal focus when editing the figures
                figures(index) = figure('Visible', 'off');
                
                % Create the figures for later classification
                createInteractiveFigure(subFolderPath, figures(index), maturationEfficiency, spot_info);
                savefig(figures(index), fullfile(subFolderPath, 'interactiveFig'))
                processingInfo.status = "figure saved";
                save(infoPath, "processingInfo")

                waitbar((index-1)/totalAcquisitions, progressBar, ['Cleaning up ', imageName, ' folder'])
                updateStatus(app, ['Cleaning up ', imageName, ' folder']);
                delete(firstFramesProjectionPath)
                delete(trackXMLPath)
            end

            waitbar(1, progressBar, 'Saving Figures')

            % make all the firgures visible at once. This will flash on the
            % screen
            for i = 1:numel(filenames)
                currentfilename = filenames{i};
                imageName = extractBefore(currentfilename, '.nd2');
                subFolderPath = fullfile(results_folder, imageName);
                set(figures(i), 'visible', 'on');
                figFilePath = fullfile(subFolderPath, 'interactiveFig');
                savefig(figures(i), figFilePath)
                close(figures(i))
            end

            ij.IJ.run("Quit","");
            close(progressBar)
            updateStatus(app, 'All Done!');
        end
        
        function complete = updateStatus(app, message)
            app.DisplayStatus.Value = message;
            complete = true;
        end
        
        function checkInput(app, inputValue, inputName)
            % checks that provided inputs are valid

            correctInput = false;
            switch inputName

                % check that the provided path points to a folder that
                % contains the scripts folder that we will call later
                case "Fiji Path"
                    possibleScriptsPath = fullfile(inputValue, 'scripts');
                    if isfolder(possibleScriptsPath)
                        app.fijiPath = inputValue;
                        correctInput = true;
                    else 
                        % if the provided folder is wrong the most common
                        % location is in the superfolder (usually on
                        % Windows installations)
                        fijiPathPieces = split(inputValue, filesep);
                        indexOfLastFijiPathPiece = length(fijiPathPieces);
                        lengthOfLastFiji = length(cell2mat(fijiPathPieces(indexOfLastFijiPathPiece)));
                        fijiFolderPath = inputValue( 1 : (length(inputValue) - lengthOfLastFiji - 1));
                        alternatePath = fullfile(fijiFolderPath, 'scripts');
                        if isfolder(alternatePath)
                            app.fijiPath = fijiFolderPath;
                            correctInput = true;
                        else
                            error('could not find scripts folder')
                        end
                    end
                    if ismac()
                        possibleExecutablePath = fullfile(app.fijiPath, "Contents/MacOS/ImageJ-macosx");
                        if ~isfile(possibleExecutablePath)
                            possibleExecutablePath = inputdlg("Paste the correct path for to the executable for ImageJ-macosx", "Manual Executable Input");
                        end
                        
                    elseif ispc()
                        possibleExecutablePath64 = fullfile(app.fijiPath, "ImageJ-win64.exe");
                        possibleExecutablePath32 = fullfile(app.fijiPath, "ImageJ-win32.exe");
                        if isfile(possibleExecutablePath64)
                            possibleExecutablePath = possibleExecutablePath64;
                        elseif isfile(possibleExecutablePath32)
                            possibleExecutablePath = possibleExecutablePath32;
                        else
                            possibleExecutablePath = inputdlg("Paste the correct path for to the executable for ImageJ-win64", "Manual Executable Input");
                        end
                    else
                        possibleExecutablePath = inputdlg("Paste the correct path for to the executable for ImageJ", "Manual Executable Input");
                    end
                    app.fijiExecutablePath = possibleExecutablePath;
                case "Input Folder Path"
                    if isfolder(inputValue)
                        correctInput = true;
                    end
                case "Spot Radius"
                    if isnumeric(inputValue)
                        correctInput = true;
                    end
                case "Quality Threshold"
                    if isnumeric(inputValue)
                        correctInput = true;
                    end
                case "Maturation Efficiency"
                    if isnumeric(inputValue)
                        correctInput = true;
                    end
            end
            if correctInput
                app.ValidInputs(inputName) = true;
            else
                app.ValidInputs(inputName) = false;
                msgbox(strjoin(["Provided", inputName, " '",...
                    inputValue, "' ", "was not valid"]), 'Invalid Selection!');
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % code adapted from Mathworks forum user Eric Delgado 
            % https://www.mathworks.com/matlabcentral/answers/1920595-directorry-for-saving-settings-from-app-designer
            appName = 'NNB_GUI';
            app.RootFolder = "";
            % PATH SEARCH
            Flag = 1;
            if isdeployed
                [~, result]    = system('path');
                app.RootFolder = char(regexpi(result, 'Path=(.*?);', 'tokens', 'once'));

                if ~isfile(fullfile(app.RootFolder, sprintf('%s.exe', appName)))
                    Flag = 0;
                end
            else
                prjPath = matlab.project.rootProject;
                appsStruct = matlab.apputil.getInstalledAppInfo;
                appIndex = strcmp({appsStruct.name}, appName);
                if any(appIndex)
                    appPath = appsStruct(appIndex).location;
                else
                    appPath = '';
                end
                %appPath = fullfile(char(com.mathworks.appmanagement.MlappinstallUtil.getAppInstallationFolder), appName);
                 
                if ~isempty(prjPath) & strcmp(prjPath.Name, appName)
                    app.RootFolder = char(prjPath.RootFolder);
                elseif isfolder(appPath)
                    app.RootFolder = appPath;
                else
                    Flag = 0;
                end
            end
            disp(app.RootFolder)
            % INITIALIZATION
            if Flag
                app.fijiPath = "";
                app.fijiExecutablePath = "";
                app.ValidInputs = containers.Map(["Fiji Path"; ...
                    "Spot Radius";"Quality Threshold"; "Maturation Efficiency";...
                    "Input Folder Path"],logical([0;0;0;0;0]));
            else
                % Error message...
                error("Initialization Failed. Could not find app installation location")
            end


        end

        % Button pushed function: SelectFijiFolderButton
        function SelectFijiFolderButtonPushed(app, event)
            % Uses MATLAB UI to select Fiji folder. On macOS this should be
            % an application on Windows you are looking for the folder
            % named Fiji.app which contains a scripts folder though if the
            % ImageJ application is selected checkInputs will fix it

            % commandwindow() and drawnow() are used to shift focus to and
            % from the application window. This doesn't always work
            commandwindow();
            if ismac
                [fijiName, fijiAppPath] = uigetfile('Fiji.app', 'Select the Fiji.app', '/Applications/');
                app.fijiPath = fullfile(fijiAppPath, fijiName);
            elseif ispc
                app.fijiPath = uigetdir('C:\', 'Select the Fiji.app folder');
            end
            checkInput(app, app.fijiPath, "Fiji Path")
            drawnow;
            figure(app.UIFigure)
            app.FijiPathEditField.Value = app.fijiPath;
        end

        % Value changed function: SpotRadiusEditField
        function SpotRadiusEditFieldValueChanged(app, event)
            spot_radius = app.SpotRadiusEditField.Value;
            checkInput(app, spot_radius, "Spot Radius")
        end

        % Value changed function: QualitythresholdEditField
        function QualitythresholdEditFieldValueChanged(app, event)
            quality_threshold = app.QualitythresholdEditField.Value;
            checkInput(app, quality_threshold, "Quality Threshold")
        end

        % Value changed function: MaturationEfficiencyEditField
        function MaturationEfficiencyEditFieldValueChanged(app, event)
            maturation_efficiency = app.MaturationEfficiencyEditField.Value;
            checkInput(app, maturation_efficiency, "Maturation Efficiency")
        end

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed(app, event)
            commandwindow();
            inputFolder = uigetdir('/Documents/', 'Select the input folder');
            checkInput(app, inputFolder, "Input Folder Path")
            drawnow;
            figure(app.UIFigure)
            app.InputPathEditField.Value = inputFolder;
        end

        % Button pushed function: ProcessImagesButton
        function ProcessImagesButtonPushed(app, event)
            % checks inputs, saves variables for next use and then starts
            % to process images

            fijiPathInput = app.FijiPathEditField.Value;
            checkInput(app, fijiPathInput, "Fiji Path")

            spot_radius = app.SpotRadiusEditField.Value;
            checkInput(app, spot_radius, "Spot Radius")

            quality_threshold = app.QualitythresholdEditField.Value;
            checkInput(app, quality_threshold, "Quality Threshold")

            maturationEfficiency = app.MaturationEfficiencyEditField.Value;
            checkInput(app, maturationEfficiency, "Maturation Efficiency")
            
            inputFolder = app.InputPathEditField.Value;
            checkInput(app, inputFolder, "Input Folder Path")

            extension = app.InputFileTypeDropDown.Value;
            channel = app.ChannelDropDown.Value;
            
            if ~all(cellfun(@(x) isequal(x, true), values(app.ValidInputs)))
                error('One or more inputs invalid')
            else
                settingsPath = fullfile(app.RootFolder,'SettingsNNB_GUI.mat');
                save(settingsPath,"fijiPathInput", "spot_radius",...
                    "quality_threshold", "inputFolder",...
                    "maturationEfficiency", "extension", "channel")
                answer = '';
                while ~strcmp(answer,'Yes')
                    answer = questdlg(['Does input folder contain only the .nd2 files you' ...
                        ' wish to process? i.e. no 3 channel images, partial timelapses,' ...
                        ' or other files/folders'], 'Folder Check', 'Yes',...
                        'No (Select another folder)', 'Cancel', 'Yes');
                    if strcmp(answer, 'Cancel') || strcmp(answer, '')
                        error('Execution canceled by folder check dialog box')
                    end
                end
                updateStatus(app, 'Processing Started');
                processImages(app, inputFolder, spot_radius,...
                    quality_threshold, maturationEfficiency, extension, channel)
            end
        end

        % Button pushed function: LoadLastSettingsButton
        function LoadLastSettingsButtonPushed(app, event)
            % settings from the last run can be loaded into the fields for
            % convenience
            
            settingsPath = fullfile(app.RootFolder,'SettingsNNB_GUI.mat');
            if isfile(settingsPath)
                load(settingsPath, 'fijiPathInput', 'spot_radius', 'quality_threshold',...
                    'inputFolder', 'maturationEfficiency', 'extension', 'channel')
                app.FijiPathEditField.Value = fijiPathInput;
                app.SpotRadiusEditField.Value = spot_radius;
                app.QualitythresholdEditField.Value = quality_threshold;
                app.InputPathEditField.Value = inputFolder;
                app.MaturationEfficiencyEditField.Value = maturationEfficiency;
                app.InputFileTypeDropDown.Value = extension;
                app.ChannelDropDown.Value = channel;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ProcessImagesTab = uitab(app.TabGroup);
            app.ProcessImagesTab.Title = 'Process Images';
            app.ProcessImagesButton = uibutton(app.ProcessImagesTab, 'push');
            app.ProcessImagesButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessImagesButtonPushed, true);
            app.ProcessImagesButton.Text = 'Process Images';
            app.SelectFijiFolderButton = uibutton(app.ProcessImagesTab, 'push');
            app.SelectFijiFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFijiFolderButtonPushed, true);
            app.SelectFijiFolderButton.Text = 'Select File';
            app.SelecttheFijiappfolderLabel = uilabel(app.ProcessImagesTab);
            app.SelecttheFijiappfolderLabel.Text = 'Select the Fiji.app folder';
            app.SelectFolderButton = uibutton(app.ProcessImagesTab, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed, true);
            app.SelectFolderButton.Text = 'Select Folder';
            app.SelecttheinputfolderLabel = uilabel(app.ProcessImagesTab);
            app.SelecttheinputfolderLabel.Text = 'Select the input folder';
            app.ProcessNNBTimelapsesLabel = uilabel(app.ProcessImagesTab);
            app.ProcessNNBTimelapsesLabel.Text = 'Process NNB Timelapses';
            app.DisplayStatus = uieditfield(app.ProcessImagesTab, 'text');
            app.LoadLastSettingsButton = uibutton(app.ProcessImagesTab, 'push');
            app.LoadLastSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadLastSettingsButtonPushed, true);
            app.LoadLastSettingsButton.Text = 'Load Last Settings';
            app.SpotRadiusEditFieldLabel = uilabel(app.ProcessImagesTab);
            app.SpotRadiusEditFieldLabel.Text = 'Spot Radius';
            app.SpotRadiusEditField = uieditfield(app.ProcessImagesTab, 'numeric');
            app.SpotRadiusEditField.ValueChangedFcn = createCallbackFcn(app, @SpotRadiusEditFieldValueChanged, true);
            app.SpotRadiusEditField.Value = 3;
            app.QualitythresholdEditFieldLabel = uilabel(app.ProcessImagesTab);
            app.QualitythresholdEditFieldLabel.Text = 'Quality threshold';
            app.QualitythresholdEditField = uieditfield(app.ProcessImagesTab, 'numeric');
            app.QualitythresholdEditField.ValueChangedFcn = createCallbackFcn(app, @QualitythresholdEditFieldValueChanged, true);
            app.QualitythresholdEditField.Value = 20;
            app.InputPathEditFieldLabel = uilabel(app.ProcessImagesTab);
            app.InputPathEditFieldLabel.Text = 'Input Path';
            app.InputPathEditField = uieditfield(app.ProcessImagesTab, 'text');
            app.MaturationEfficiencyEditFieldLabel = uilabel(app.ProcessImagesTab);
            app.MaturationEfficiencyEditFieldLabel.Text = 'Maturation Efficiency';
            app.MaturationEfficiencyEditField = uieditfield(app.ProcessImagesTab, 'numeric');
            app.MaturationEfficiencyEditField.ValueChangedFcn = createCallbackFcn(app, @MaturationEfficiencyEditFieldValueChanged, true);
            app.MaturationEfficiencyEditField.Value = 0.7;
            app.InputFileTypeDropDownLabel = uilabel(app.ProcessImagesTab);
            app.InputFileTypeDropDownLabel.Text = 'Input File Type';
            app.InputFileTypeDropDown = uidropdown(app.ProcessImagesTab);
            app.InputFileTypeDropDown.Items = {'*.nd2', '*.tif'};
            app.InputFileTypeDropDown.Value = '*.nd2';
            app.ChannelDropDownLabel = uilabel(app.ProcessImagesTab);
            app.ChannelDropDownLabel.Text = 'Channel';
            app.ChannelDropDown = uidropdown(app.ProcessImagesTab);
            app.ChannelDropDown.Items = {'1', '2', '3'};
            app.ChannelDropDown.Value = '1';
            app.FijiPathEditField = uieditfield(app.ProcessImagesTab, 'text');
            app.FijiPathEditFieldLabel = uilabel(app.ProcessImagesTab);
            app.FijiPathEditFieldLabel.Text = 'Fiji Path';
            app.ColocalizationCalculationsTab = uitab(app.TabGroup);
            app.ColocalizationCalculationsTab.Title = 'Colocalization Calculations';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NNB_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end