classdef appTest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        MaturationEfficiencyEditField   matlab.ui.control.NumericEditField
        MaturationEfficiencyEditFieldLabel  matlab.ui.control.Label
        LoadLastSettingsButton          matlab.ui.control.Button
        DisplayStatus                   matlab.ui.control.EditField
        ProcessNNBTimelapsesLabel       matlab.ui.control.Label
        InputPathEditField              matlab.ui.control.EditField
        InputPathLabel                  matlab.ui.control.Label
        CodePathEditField               matlab.ui.control.EditField
        CodePathEditFieldLabel          matlab.ui.control.Label
        FijiPathEditField               matlab.ui.control.EditField
        FijiPathEditFieldLabel          matlab.ui.control.Label
        SelecttheinputfolderLabel       matlab.ui.control.Label
        SelectFolderButton              matlab.ui.control.Button
        QualitythresholdEditField       matlab.ui.control.NumericEditField
        QualitythresholdEditFieldLabel  matlab.ui.control.Label
        SpotRadiusEditField             matlab.ui.control.NumericEditField
        SpotRadiusEditFieldLabel        matlab.ui.control.Label
        SelectthecodefolderLabel        matlab.ui.control.Label
        SelectCodeFolder                matlab.ui.control.Button
        SelecttheFijiappfolderLabel     matlab.ui.control.Label
        SelectFileButton                matlab.ui.control.Button
        ProcessImagesButton             matlab.ui.control.Button
    end


    methods (Access = private)

        function [] = processImages(app, fijiPath, inputFolder, spot_radius, quality_threshold, maturationEfficiency)
            updateStatus(app, 'Processing Started')
            %add the scripts folder to the path so matlab knows where ImageJ script is
            scriptsPath = fullfile(fijiPath, 'scripts');
            addpath(scriptsPath)

            results_folder = fullfile(inputFolder, 'Results');
            mkdir(results_folder)

            % I should probably put this somewhere else (as a setting?)
            ext = '*.nd2';

            % Get the list of files with the correct extension in the provided folder
            files = dir(fullfile(inputFolder,ext));
            filenames = {files.name};
            filenames = sort(filenames);

            % Start ImageJ for image analysis
            ImageJ;
            import ij.*

            % create the array of figures so that we can loop through them at the end
            figures = gobjects(1,numel(filenames));

            % Process each image
            for index = 1:length(filenames)
                currentfilename = filenames{index};
                imageName = extractBefore(currentfilename, '.nd2');
                subFolderPath = fullfile(results_folder, imageName);

                updateStatus(app, ['Processing ', imageName])
                mkdir(subFolderPath);


                convertToTiff(inputFolder, subFolderPath, currentfilename);

                tifFilePath = fullfile(subFolderPath, [imageName, '.tif']);

                [~, ~, OriginalStack] = generateFirstFramesProjection(tifFilePath);
                firstFramesProjection = fullfile(subFolderPath, 'First3frames.tif');

                saveTrackStatisticsCSV(firstFramesProjection, subFolderPath, spot_radius, quality_threshold)

                prepareFolderForFigureCreation(subFolderPath, OriginalStack)

                % instantiate the figure and make it invisible so that MATLAB doesn't steal
                % focus when editing the figures
                figures(index) = figure('visible', 'off');
                % actually add the traces to the figure
                createInteractiveFigure(subFolderPath, figures(index), maturationEfficiency);

            end

            % make all the firgures visible for future use
            for i = 1:numel(filenames)
                currentfilename = filenames{i};
                imageName = extractBefore(currentfilename, '.nd2');
                subFolderPath = fullfile(results_folder, imageName);
                set(figures(i), 'visible', 'on');
                figFilePath = fullfile(subFolderPath, 'interactiveFig');
                savefig(figures(i), figFilePath)
                close(figures(i))
            end

            ij.IJ.run("Quit","");
            updateStatus(app, 'All Done!');
        end
        
        function complete = updateStatus(app, message)
            app.DisplayStatus.Value = message;
            complete = true;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectFileButton
        function SelectFileButtonPushed(app, event)
            if ismac
                [fijiName, fijiAppPath] = uigetfile('Fiji.app', 'Select the Fiji.app', '/Applications/');
            elseif ispc
                [fijiName, fijiAppPath] = uigetdir('C:\', 'Select the Fiji.app folder');
            end

            fijiPath = fullfile(fijiAppPath, fijiName);
            if ~strcmp(fijiName, 'Fiji.app')
                error(['Selected name (', fijiName, ') does not match Fiji.app'])
            end
            app.FijiPathEditField.Value = fijiPath;

        end

        % Button pushed function: SelectCodeFolder
        function SelectCodeFolderPushed(app, event)
            codeFolder = uigetdir('/Documents/', 'Select the code folder');
            codePathPieces = split(codeFolder, filesep);
            indexOfLastPiece = length(codePathPieces);
            codeFolderName = codePathPieces(indexOfLastPiece);
            if ~(strcmp(codeFolderName, 'TIRF_ProcessingCode') || strcmp(codeFolderName,'TIRF_ProcessingCode-main'))
                error(['Selected name (', codeFolderName, ') does not match TIRF_ProcessingCode(-main)'])
            end
            app.CodePathEditField.Value = codeFolder;
        end

        % Value changed function: SpotRadiusEditField
        function SpotRadiusEditFieldValueChanged(app, event)
            spot_radius = app.SpotRadiusEditField.Value;
        end

        % Value changed function: QualitythresholdEditField
        function QualitythresholdEditFieldValueChanged(app, event)
            quality_threshold = app.QualitythresholdEditField.Value;
        end

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed(app, event)
            inputFolder = uigetdir('/Documents/', 'Select the input folder');
            if inputFolder == 0
                error('Execution canceled by folder selection dialog box')
            end
            app.InputPathEditField.Value = inputFolder;
        end

        % Button pushed function: ProcessImagesButton
        function ProcessImagesButtonPushed(app, event)
            %TODO: need to check if these have been set already before
            %running
            fijiPath = app.FijiPathEditField.Value;
            inputFolder = app.InputPathEditField.Value;
            spot_radius = app.SpotRadiusEditField.Value;
            quality_threshold = app.QualitythresholdEditField.Value;
            maturationEfficiency = app.MaturationEfficiencyEditField.Value;
            codeFolder = app.CodePathEditField.Value;
            appPath = mfilename('fullpath');
            appPathPieces = split(appPath, filesep);
            indexOfLastAppPathPiece = length(appPathPieces);
            appFolderPathSpaces = cell2mat(join(appPathPieces(1:indexOfLastAppPathPiece-1)));
            appFolderPath = strrep(appFolderPathSpaces, ' ', filesep);
            settingsPath = fullfile(appFolderPath,'AppSettings.mat');
            save(settingsPath,"fijiPath","spot_radius",...
            "quality_threshold", "codeFolder", "inputFolder", "maturationEfficiency")
            answer = '';
            while ~strcmp(answer,'Yes')
                answer = questdlg(['Does input folder contain only the .nd2 files you' ...
                    ' wish to process? i.e. no 3 channel images, partial timelapses,' ...
                    ' or other files/folders'], 'Folder Check', 'Yes',...
                    'No (Select another folder)', 'Cancel', 'Yes');
                if strcmp(answer, 'Cancel') || strcmp(answer, '')
                    error('Execution canceled by folder check dialog box')
                end
            end
            processImages(app, fijiPath, inputFolder, spot_radius, quality_threshold, maturationEfficiency)
        end

        % Button pushed function: LoadLastSettingsButton
        function LoadLastSettingsButtonPushed(app, event)
            appPath = mfilename('fullpath');
            appPathPieces = split(appPath, filesep);
            indexOfLastAppPathPiece = length(appPathPieces);
            appFolderPathSpaces = cell2mat(join(appPathPieces(1:indexOfLastAppPathPiece-1)));
            appFolderPath = strrep(appFolderPathSpaces, ' ', filesep);
            settingsPath = fullfile(appFolderPath,'AppSettings.mat');
            load(settingsPath, 'fijiPath', 'spot_radius', 'quality_threshold', 'codeFolder', 'inputFolder', 'maturationEfficiency')
            app.FijiPathEditField.Value = fijiPath;
            app.SpotRadiusEditField.Value = spot_radius;
            app.QualitythresholdEditField.Value = quality_threshold;
            app.CodePathEditField.Value = codeFolder;
            app.InputPathEditField.Value = inputFolder;
            app.MaturationEfficiencyEditField.Value = maturationEfficiency;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ProcessImagesButton = uibutton(app.UIFigure, 'push');
            app.ProcessImagesButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessImagesButtonPushed, true);
            app.ProcessImagesButton.Text = 'Process Images';
            app.SelectFileButton = uibutton(app.UIFigure, 'push');
            app.SelectFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFileButtonPushed, true);
            app.SelectFileButton.Text = 'Select File';
            app.SelecttheFijiappfolderLabel = uilabel(app.UIFigure);
            app.SelecttheFijiappfolderLabel.Text = 'Select the Fiji.app folder';
            app.SelectCodeFolder = uibutton(app.UIFigure, 'push');
            app.SelectCodeFolder.ButtonPushedFcn = createCallbackFcn(app, @SelectCodeFolderPushed, true);
            app.SelectCodeFolder.Text = 'Select File';
            app.SelectthecodefolderLabel = uilabel(app.UIFigure);
            app.SelectthecodefolderLabel.Text = 'Select the code folder';
            app.SpotRadiusEditFieldLabel = uilabel(app.UIFigure);
            app.SpotRadiusEditFieldLabel.Text = 'Spot Radius';
            app.SpotRadiusEditField = uieditfield(app.UIFigure, 'numeric');
            app.SpotRadiusEditField.ValueChangedFcn = createCallbackFcn(app, @SpotRadiusEditFieldValueChanged, true);
            app.SpotRadiusEditField.Value = 3;
            app.QualitythresholdEditFieldLabel = uilabel(app.UIFigure);
            app.QualitythresholdEditFieldLabel.Text = 'Quality threshold';
            app.QualitythresholdEditField = uieditfield(app.UIFigure, 'numeric');
            app.QualitythresholdEditField.ValueChangedFcn = createCallbackFcn(app, @QualitythresholdEditFieldValueChanged, true);
            app.QualitythresholdEditField.Value = 50;
            app.SelectFolderButton = uibutton(app.UIFigure, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed, true);
            app.SelectFolderButton.Text = 'Select Folder';
            app.SelecttheinputfolderLabel = uilabel(app.UIFigure);
            app.SelecttheinputfolderLabel.Text = 'Select the input folder';
            app.FijiPathEditFieldLabel = uilabel(app.UIFigure);
            app.FijiPathEditFieldLabel.Text = 'Fiji Path';
            app.FijiPathEditField = uieditfield(app.UIFigure, 'text');
            app.CodePathEditFieldLabel = uilabel(app.UIFigure);
            app.CodePathEditFieldLabel.Text = 'Code Path';
            app.CodePathEditField = uieditfield(app.UIFigure, 'text');
            app.InputPathLabel = uilabel(app.UIFigure);
            app.InputPathLabel.Text = 'Input Path';
            app.InputPathEditField = uieditfield(app.UIFigure, 'text');
            app.ProcessNNBTimelapsesLabel = uilabel(app.UIFigure);
            app.ProcessNNBTimelapsesLabel.Text = 'Process NNB Timelapses';
            app.DisplayStatus = uieditfield(app.UIFigure, 'text');
            app.LoadLastSettingsButton = uibutton(app.UIFigure, 'push');
            app.LoadLastSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadLastSettingsButtonPushed, true);
            app.LoadLastSettingsButton.Text = 'Load Last Settings';
            app.MaturationEfficiencyEditFieldLabel = uilabel(app.UIFigure);
            app.MaturationEfficiencyEditFieldLabel.Text = 'Maturation Efficiency';
            app.MaturationEfficiencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaturationEfficiencyEditField.Value = 0.7;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appTest

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end